classdef CircleFittingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SaveDataButton                matlab.ui.control.Button
        PlotRtButton                  matlab.ui.control.Button
        MaxradiuspixelEditField       matlab.ui.control.NumericEditField
        MaxradiuspixelEditFieldLabel  matlab.ui.control.Label
        StartProcessingButton         matlab.ui.control.Button
        CameraDropDown                matlab.ui.control.DropDown
        CameraDropDownLabel           matlab.ui.control.Label
        CirclefittingLabel            matlab.ui.control.Label
        MinradiuspixelEditField       matlab.ui.control.NumericEditField
        MinradiuspixelEditFieldLabel  matlab.ui.control.Label
        CenterypixelEditField         matlab.ui.control.NumericEditField
        CenterypixelEditFieldLabel    matlab.ui.control.Label
        CenterxpixelEditField         matlab.ui.control.NumericEditField
        CenterxpixelEditFieldLabel    matlab.ui.control.Label
        LoadMovieButton               matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        FrameSlider                   matlab.ui.control.Slider
        FrameSliderLabel              matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        pixel_value_nm % Variable to store pixel size
        x0_image % Variable to store the x coordinate of center (estimation)
        y0_image % Variable to store the y coordinate of center (estimation)
        radmin % minimum value for the radius
        radmax %maximum value for the radius
        cent_area %search radius of the center itself
    end
    
    properties (Access = private)
        MovieData % Array to store all frames of the movie
        CurrentFrame = 1 % Index of the current frame being displayed
        Num_frame
        img_wBC  
        AllR %vector of all 10x radii
        Figures10xRad
    end
    
    methods (Access = private)
        %Custom functions go here
        function result = myCustomFunction(app, input1, input2) %example for a custom function
            % This is a custom function within the app
            result = input1 + input2; % Example: simple addition
            disp(['The result is: ', num2str(result)]);
        end
        function Coordinates = CF_circle_contour_func(app, i,radmin,radmax,cent_area)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % this code finds a circle that fits the FtsZ ring the best. This is done
            % by scanning the three parameters (center of the circle and radius) and
            % averaging over the intensities of the image over the circle. The circle
            % with the highest average intensity is the best fit.
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %i=I_fluor(:,:,t); %difference between ZringA input and ZringB input arg names
            %%%%%%%%%%%%%%%%%%%%%%%
            % function input parameters:%
            %%%%%%%%%%%%%%%%%%%%%%%
            % i - image
            % radmin - the smallest radius in the radius scan
            % radmax - the largest radius in the radius scan
            % cent_area - the size of a square over which it will search for the center
            % of the circle.

            I_fluor=i;
            I_fluor=double(I_fluor);
            I_fluor1=I_fluor;
            [R,C]=size(I_fluor);


            centx= (R+1)/2; centy = (C+1)/2;


            % Scan over all the circles and find the best %

            intensity1x_central=nan(numel(-(app.cent_area-1)/2:(app.cent_area-1)/2),numel(-(app.cent_area-1)/2:(app.cent_area-1)/2),numel(radmin:radmax));
            Count1x_central=nan(size(intensity1x_central));
            Cr=0;
            for rad=radmin:radmax
                Cr=Cr+1;
                Cx=0;
                for indx= -(app.cent_area-1)/2:(app.cent_area-1)/2

                    Cx=Cx+1;
                    Cy=0;
                    for indy= -(app.cent_area-1)/2:(app.cent_area-1)/2

                        Cy=Cy+1;
                        cenx=centx+indx; ceny=centy+indy;
                        int=0;
                        count=0;
                        for ix=1:C
                            for iy=1:R
                                if(round(sqrt((ix-cenx)^2+(iy-ceny)^2))==rad)
                                    int=int+I_fluor(iy,ix);
                                    count=count+1;
                                    I_fluor1(iy,ix)=0;
                                end
                            end
                        end
                        intensity((app.cent_area-1)/2+indy+1,(app.cent_area-1)/2+indx+1,rad)=int/count; %calculates the average intensity along the circumference of each circle
                        %intensity((cent_area-1)/2+indy+1,(cent_area-1)/2+indx+1,rad)=int; %calculates the  total along the circumference of each circle
                        intensity1x_central(Cy,Cx,Cr)=int;
                        Count1x_central(Cy,Cx,Cr)=count;
                    end
                end
            end

            [y_max_val,max_y]=max(intensity);
            [max_val,max_x]=max(y_max_val);
            [best_int_val,best_rad]=max(max_val);
            best_x=max_x(1,1,best_rad); best_y=max_y(1,best_x,best_rad);
            center_x=centx+best_x-((app.cent_area+1)/2);
            center_y=centy+best_y-((app.cent_area+1)/2);


            Coordinates=[center_x, center_y,best_rad];
            %return; %not needed because of the 'end' v
        end
        function CenteredImg=CF_circle_contour_func_X10(app, img,x0,y0,rad_course,gap,Borders,R,C)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % this code gets the parameters of the ring at 1pix precision. it uses it as
            % initial conditions for the search at high precision (0.1 pix) of the ring
            % center and radius
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            %%%%%%%%%%%%%%%%%%%%%%%
            % function input parameters:%
            %rad_course=rad; %difference between ZringA input and ZringB input arg names
            %img1 = img(:,:,1);
            %%%%%%%%%%%%%%%%%%%%%%%

            % I_fluor - image
            % x0,y0,r - the ring's parameters that were found at 1pix precision
            % search_area - search size in pixels

            x02=x0+gap; y02=y0+gap;
            search_area=1;
            img=double(img);
            I_fluor2(1:R+gap*2,1:C+gap*2) = img((Borders(1)-gap):(Borders(2)+gap),(Borders(3)-gap):(Borders(4)+gap));  %image with [x0_matlab+gap,y0_matlab+gap]
            % (1x center) as the center. Since the center is shifted (by gap), the borders are
            % re-adjusted accordingly. the final image is still 31x31 pixels
            I_fluor2 = double(I_fluor2);
            [R1,C1]=size(I_fluor2);


            % Interpolate the image (expand it's dimensions by X100)


            [X,Y] = meshgrid(1:1:R1); % 1,2,3,4,5
            [XI,YI] = meshgrid(1:.1:R1); %1,1.1,1.2,1.3,1.4..
            I_fluorI=interp2(X,Y,I_fluor2,XI,YI,'linear');
            [R2,C2]=size(I_fluorI);


            % Scan over all the circles and find the best %

            intensity=zeros((y02*10-9)+search_area*5,(x02*10-9)+search_area*5,rad_course*10+5);
            intensity10x_central=nan(11,11,11);
            Count10x_central=nan(size(intensity10x_central));
            Cr=0;
            int_mat=[]; factor =1.2;
            for rad=rad_course*10-5:rad_course*10+5
                Cr=Cr+1;
                Cx=0;
                for centx=(x02*10-9)-search_area*5:(x02*10-9)+search_area*5
                    Cx=Cx+1;
                    Cy=0;
                    for centy=(y02*10-9)-search_area*5:(y02*10-9)+search_area*5
                        Cy=Cy+1;
                        int=0;
                        count=0;
                        for ix=x02*10-9-round(rad*factor):x02*10-9+round(rad*factor)
                            for iy=y02*10-9-round(rad*factor):y02*10-9+round(rad*factor)
                                if(round(sqrt((ix-centx)^2+(iy-centy)^2))==rad)
                                    int_mat(iy,ix) = I_fluorI(iy,ix);
                                    int=int+I_fluorI(iy,ix);
                                    count=count+1;

                                end
                            end
                        end

                        intensity(centy,centx,rad)=int/count; %calculates the average intensity along the circumference of each circle
                        %intensity(centy,centx,rad)=int; %calculates the  total along the circumference of each circle
                        intensity10x_central(Cy,Cx,Cr)=int;
                        Count10x_central(Cy,Cx,Cr)=count;
                    end
                end
                % int_mat(int_mat==0)=NaN;
                % figure; surf(int_mat,'EdgeColor','none');

            end

            [y_max_val,max_y]=max(intensity);
            [max_val,max_x]=max(y_max_val);
            [best_int_val,best_rad]=max(max_val);
            best_x=max_x(1,1,best_rad); best_y=max_y(1,best_x,best_rad);
            center_x=best_x;
            center_y=best_y;
            rad_best = best_rad/10;

            % generate a new matrix, similar in size to the original I_fluor, where the
            % ring is centered at the middle of the matrix. This matrix is the result
            % of this whole function


            R3=R1-2*gap; C3=C1-2*gap;

            for ii=-(R3-1)/2:(R3-1)/2
                for jj=-(R3-1)/2:(R3-1)/2
                    I_flour_cent(R3-(R3-1)/2+jj,R3-(R3-1)/2+ii)=I_fluorI(center_y+10*jj,center_x+10*ii);
                end
            end


            CenteredImg=I_flour_cent;
        end
        function Coordinates10x=CF_circle_contour_func_X10_no_shift(app, img1,x0,y0,rad_course,Borders,R,C)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % this code gets the parameters of the ring at 1pix precision. it uses it as
            % initial conditions for the search at high precision (0.1 pix) of the ring
            % center and radius
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %rad_course=rad; %difference between ZringA input and ZringB input arg names
            %img1 = img(:,:,1);
            %%%%%%%%%%%%%%%%%%%%%%%
            % function input parameters:%
            %%%%%%%%%%%%%%%%%%%%%%%

            % I_fluor - image
            % x0,y0,r - the ring's parameters that were found at 1pix precision
            % search_area - search size in pixels

            x02=x0+1; y02=y0+1; %We add 1 for the interpolation, because if we add more rows and columns than the center will also move.
            search_area=1; %the search will be within 1 pixel (or 10 interpolated query points)
            img1=double(img1);
            I_fluor2(1:R+2,1:C+2) = img1((Borders(1)-1):(Borders(2)+1),(Borders(3)-1):(Borders(4)+1)); %expand the 31x31 pixels image to 33x33
            % by adding more data from the large original image.
            I_fluor2 = double(I_fluor2);
            [R1,C1]=size(I_fluor2);
            % Interpolate the image (expand it's dimensions by X100)


            [X,Y] = meshgrid(1:1:R1); %1,2,3,4..
            [XI,YI] = meshgrid(1:.1:R1); %1,1.1,1.2,1.3..
            I_fluorI=interp2(X,Y,I_fluor2,XI,YI,'linear');
            [R2,C2]=size(I_fluorI);


            % Scan over all the circles and find the best coordinates and radius
            RRR=rad_course*10-5:rad_course*10+5;
            XXX=(x02*10-9)-search_area*5:(x02*10-9)+search_area*5;
            YYY=(y02*10-9)-search_area*5:(y02*10-9)+search_area*5;

            intensity=zeros((y02*10-9)+search_area*5,(x02*10-9)+search_area*5,rad_course*10+5); %intensity(y,x,z)
            intensity10x_central_noshift=nan(11,11,11);
            Count10x_central_noshift=nan(size(intensity10x_central_noshift));
            Cr=0;
            int_mat=[]; factor =1.2;
            for rad=rad_course*10-5:rad_course*10+5
                Cr=Cr+1;
                Cx=0;
                for centx=(x02*10-9)-search_area*5:(x02*10-9)+search_area*5
                    Cx=Cx+1;
                    Cy=0;
                    for centy=(y02*10-9)-search_area*5:(y02*10-9)+search_area*5
                        Cy=Cy+1;
                        int=0;
                        count=0;
                        for ix=x02*10-9-round(rad*factor):x02*10-9+round(rad*factor)
                            for iy=y02*10-9-round(rad*factor):y02*10-9+round(rad*factor)
                                if(round(sqrt((ix-centx)^2+(iy-centy)^2))==rad)
                                    int_mat(iy,ix) = I_fluorI(iy,ix);
                                    int=int+I_fluorI(iy,ix);
                                    count=count+1;

                                end
                            end
                        end
                        intensity(centy,centx,rad)=int/count; %calculates the average intensity along the circumference of each circle
                        %intensity(centy,centx,rad)=int; %calculates the  total along the circumference of each circle
                        intensity10x_central_noshift(Cy,Cx,Cr)=int;
                        Count10x_central_noshift(Cy,Cx,Cr)=count;
                    end
                end
            end

            [y_max_val,max_y]=max(intensity);
            [max_val,max_x]=max(y_max_val);
            [best_int_val,best_rad]=max(max_val);
            best_x=max_x(1,1,best_rad); best_y=max_y(1,best_x,best_rad);
            center_x=(best_x+9)/10-1; %rescaling from the interpolated grid to the original grid. why do we subtract 1? because we added earlier?
            center_y=(best_y+9)/10-1;
            rad_best = double(best_rad/10);

            Coordinates10x=[center_x, center_y,rad_best];
        end        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CenterxpixelEditField
        function CenterxpixelEditFieldValueChanged(app, event)
             app.x0_image = app.CenterxpixelEditField.Value;
            assignin('base', 'x0_image', app.x0_image);
            disp(['The value entered for x0 is: ', num2str(app.x0_image)]);
            
        end

        % Value changed function: CenterypixelEditField
        function CenterypixelEditFieldValueChanged(app, event)
            app.y0_image = app.CenterypixelEditField.Value;
            assignin('base', 'y0_image', app.y0_image);
            disp(['The value entered for y0 is: ', num2str(app.y0_image)]);
            
        end

        % Value changed function: CameraDropDown
        function CameraDropDownValueChanged(app, event)
            selectedOption  = app.CameraDropDown.Value;
            % Set PixSize based on the selected option
            switch selectedOption
                case 'Sona'
                    app.pixel_value_nm = 65;
                    app.cent_area = 5;
                case 'Retiga'
                    app.pixel_value_nm = 45;
                    app.cent_area = 5;
                case 'Coolsnap'
                    app.pixel_value_nm = 61;
                    app.cent_area = 5;
                otherwise
                    % Default case (optional, in case of an unexpected value)
                    app.pixel_value_nm = NaN;
                    warning('Unexpected option selected.');
            end
            % Assign PixSize to the MATLAB base workspace
            assignin('base', 'pixel_value_nm', app.pixel_value_nm);
            % Display the result (optional, for debugging)
            disp(['PixSize set to: ', num2str(app.pixel_value_nm)]);
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            % Get the current frame index from the slider
            app.CurrentFrame = round(app.FrameSlider.Value);

            % Display the selected frame
            imshow(app.MovieData(:, :, app.CurrentFrame), [], 'Parent', app.UIAxes);
            title(app.UIAxes, ['Frame ', num2str(app.CurrentFrame)]);           
        end

        % Button pushed function: LoadMovieButton
        function LoadMovieButtonPushed(app, event)
            % Let the user select a .tiff file
            [fileName, filePath] = uigetfile('*.tiff;*.tif', 'Select a TIFF Movie');

            if isequal(fileName, 0)
                disp('File selection canceled.');
                return;
            end

            % Read the .tiff file into an array
            fullFileName = fullfile(filePath, fileName);
            info = imfinfo(fullFileName); % Get metadata about the TIFF file
            numFrames = numel(info); % Number of frames in the movie
            app.Num_frame = numFrames;
            % Preallocate a 3D array to store the movie data
            app.MovieData = zeros(info(1).Height, info(1).Width, numFrames, 'uint16');

            % Load each frame into the array
            for k = 1:numFrames
                app.MovieData(:, :, k) = imread(fullFileName, k);
            end

            % Update the slider properties
            app.FrameSlider.Limits = [1, numFrames];
            app.FrameSlider.Value = 1;
            app.FrameSlider.MajorTicks = 1:round(numFrames/10):numFrames;
            app.FrameSlider.MinorTicks = [];

            % Display the first frame
            imshow(app.MovieData(:, :, 1), [], 'Parent', app.UIAxes);
            title(app.UIAxes, 'Frame 1');
            assignin('base', 'img_wBC', app.MovieData);
            app.img_wBC=app.MovieData;
            disp(['Movie loaded: ', fileName]);
        end

        % Value changed function: MinradiuspixelEditField
        function MinradiuspixelEditFieldValueChanged(app, event)
            app.radmin = app.MinradiuspixelEditField.Value;
            assignin('base', 'radmin', app.radmin);
            disp(['The value entered for radmin is: ', num2str(app.radmin)]);
            
        end

        % Value changed function: MaxradiuspixelEditField
        function MaxradiuspixelEditFieldValueChanged(app, event)
            app.radmax = app.MaxradiuspixelEditField.Value;
            assignin('base', 'radmax', app.radmax);
            disp(['The value entered for radmax is: ', num2str(app.radmax)]);
            
        end

        % Button pushed function: StartProcessingButton
        function StartProcessingButtonPushed(app, event)
            % here the processing will be done
            %result = app.myCustomFunction(10, 20); %example for calling a custom function
            %disp(['Result from custom function: ', num2str(result)]);

            app.cent_area=5;
            for t = 1:app.Num_frame
                
                    [R_main,C_main]=size(app.img_wBC(:,:,t)); %size of raw image
                    BC_borders= [1,10,1,10];% The background is determined from a 10x10 area at the top left square of the image
                    I_fluor_BC(:,:,t)=app.img_wBC(BC_borders(1):BC_borders(2),BC_borders(3):BC_borders(4),t); %Image of the background only
                    mean_BC(t) = mean2(I_fluor_BC(:,:,t)); %mean background only
                    stDev_BC(t)=std2(I_fluor_BC(:,:,t)); %standard deviation of background only
                    mean_imageI_all(t) = mean2(app.img_wBC(:,:,t)); %mean intensity of the raw image
                    img(:,:,t) = app.img_wBC(:,:,t)-mean_BC(t);  %image after background subtraction
                    x0_matlab = app.x0_image+1; y0_matlab =app.y0_image+1; %imagej to matlab center coordinates
                    Borders=[y0_matlab-15,y0_matlab + 15,x0_matlab - 15,x0_matlab + 15];
                    Borders_mat(:,t) = Borders;% Borders - (y1,y2,x1,x2) - a rectangle that contains the ring
                    I_fluor(:,:,t) = img(Borders(1):Borders(2),Borders(3):Borders(4),t);        %31x31 pixel image, with [x0_matlab,y0_matlab] (guess) as the center
                    I_fluor(:,:,t) = double(I_fluor(:,:,t));
                    
                    if t==1
                        fprintf('The value of radmin is: %g nm = %g pixel\n', app.radmin*app.pixel_value_nm,app.radmin);
                        fprintf('The value of radmmax is: %g nm = %g pixel\n', app.radmax*app.pixel_value_nm,app.radmax);
                    end
                    % 1x - start with this
                    a = app.CF_circle_contour_func(I_fluor(:,:,t),app.radmin,app.radmax,app.cent_area); %TBD - itegrate this function
                    x0=a(1); y0=a(2); %the 1x center coordinates, in the 31x31 pixel image
                    x0_mat(t) = x0; y0_mat(t) = y0;
                    rad=a(3); % the 1x radius
                    rad_mat(t) = rad;
                    %fprintf('The value of 1x rad is: %g nm = %g pixel\n', rad*app.pixel_value_nm,rad);
                    x0_1x_matlab = x0+Borders(3)-1; y0_1x_matlab = y0+Borders(1)-1; %the 1x center coordinates, in the [R_main x C_main] original uncropped image
                    app.x0_image=x0_1x_matlab-1; app.y0_image=y0_1x_matlab-1; %matlab to imageJ center coordinates
                    centers_1x =[x0_1x_matlab,y0_1x_matlab]; %1x centers_matlab
                    x0_image_1x_mat(t)= app.x0_image; y0_image_1x_mat(t) = app.y0_image; %image 1x centers all frame
                    [R,C]=size(I_fluor(:,:,t));
                    %border adjustment to get centered image
                    gap_x0 = abs(16-x0);
                    gap_y0 = abs(16-y0);
                    if (gap_x0>=gap_y0)
                        gap= gap_x0+1;
                    else
                        gap=gap_y0+1;
                    end
                    gap_mat(t)= gap; %gap for all frames

                    % 10x - If 1x work, move on to this
                    %10x precision function
                    b(:,:,t) = app.CF_circle_contour_func_X10(img(:,:,t),x0,y0,rad,gap,Borders,R,C); %TBD - integrate this function
                    b2 = app.CF_circle_contour_func_X10_no_shift(img(:,:,t),x0,y0,rad,Borders,R,C); % best fit centers and radius
                    x0_10x=b2(1);y0_10x=b2(2); %the 10x center coordinates, in the 31x31 pixel image
                    r=b2(3); % the 10x radius                    
                    fprintf('At frame %d The value of 10x r is: %g nm = %g pixel\n', t, r*app.pixel_value_nm,r);
                    

                    % Final centers and radius of the z-ring
                    x02=b2(1); y02=b2(2); rad2=b2(3);
                    centers =[x02,y02]; %unused
                    x0_10x_matlab = x0_10x+Borders(3)-1; y0_10x_matlab = y0_10x+Borders(1)-1; %the 10x center coordinates, in the [R_main x C_main] original uncropped image
                    x0_image_10x=x0_10x_matlab-1; y0_image_10x=y0_10x_matlab-1; %matlab to imageJ center coordinates

                    centers_10x =[x0_10x_matlab,y0_10x_matlab]; %10x centers_matlab
                    CentersArr_10x(t,1:2)=centers_10x;

                    x0_image_10x_mat(t)= x0_image_10x; y0_image_10x_mat(t) = y0_image_10x; %image 10x centers all frame

                    r_mat(t) = r; % best radii of all frame
                    r_mean= mean(r_mat);
                    r_std= std(r_mat);                    

                    I_fluor3(:,:,t) = b(:,:,t); %final image% change to b1 when use cytoplasmic BC removal
                    
                    % % plotting
                    % app.Figures10xRad(t)=figure;
                    % % Create an axes inside the figure
                    % ax = axes(app.Figures10xRad(t)); % Create an axes handle in the new figure
                    % 
                    % % Plot the image in the new axes
                    % imagesc(ax, app.img_wBC(:, :, t)); % Use 'ax' as the target for imagesc
                    % colormap(ax, gray);               % Set the colormap for the new axes
                    % 
                    % %imagesc(app.img_wBC(:,:,t));colormap(gray);
                    % assignin('base', 'Figures10xRad', app.Figures10xRad);
                    %hold on;
                    % px_circle=viscircles(centers_10x,r_mat(t),'EdgeColor','b'); % plot the circle bound area %10x precision
                    % plot(x0_10x_matlab, y0_10x_matlab,'.', 'color', 'red', 'markersize', 10); %plot the center %10x precision
                    % title(sprintf('Frame %g. Circle fit \n r=%g pixel = %g nm',t,round(r_mat(t),2), round(r_mat(t)*app.pixel_value_nm,2))) %*pixel_value_nm
                    % hold off;
                
            end
            fprintf('Finished analyzing %d frames\n', app.Num_frame);
            app.AllR=r_mat;
        end

        % Button pushed function: PlotRtButton
        function PlotRtButtonPushed(app, event)
            figure;
            disp("AllR")
            disp(app.AllR)
            disp("nm")
            disp(app.pixel_value_nm)
            scatter(1:app.Num_frame,app.AllR*app.pixel_value_nm,'MarkerFaceColor','k')
            title('Radius Vs. Frame')
            xlabel('Frame');ylabel('Radius(nm)');
            ylim([min(app.AllR)*app.pixel_value_nm*0.9 max(app.AllR)*app.pixel_value_nm*1.1]);xlim([0 app.Num_frame+1]);
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            % Sample vector (replace this with your actual vector)
            data = app.AllR*app.pixel_value_nm; % Assuming 'MyVector' is the vector you want to save

            % Open the "Save As" dialog
            [fileName, filePath] = uiputfile('*.xlsx', 'Save Data As');

            % Check if the user canceled the dialog
            if isequal(fileName, 0)
                disp('User canceled save operation.');
                return;
            end

            % Full file path
            fullFileName = fullfile(filePath, fileName);

            % Write the data to the Excel file
            try
                writematrix(data, fullFileName);
                disp(['Data saved successfully to ', fullFileName]);
            catch ME
                % Handle any errors that might occur
                disp('Error saving the file:');
                disp(ME.message);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Input')
            zlabel(app.UIAxes, 'Z')
            app.FrameSliderLabel = uilabel(app.UIFigure);
            app.FrameSliderLabel.Text = 'Frame Slider';
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Signaturecrop.png');
            app.LoadMovieButton = uibutton(app.UIFigure, 'push');
            app.LoadMovieButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMovieButtonPushed, true);
            app.LoadMovieButton.Text = 'Load Movie';
            app.CenterxpixelEditFieldLabel = uilabel(app.UIFigure);
            app.CenterxpixelEditFieldLabel.Text = 'Center x (pixel)';
            app.CenterxpixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.CenterxpixelEditField.ValueChangedFcn = createCallbackFcn(app, @CenterxpixelEditFieldValueChanged, true);
            app.CenterypixelEditFieldLabel = uilabel(app.UIFigure);
            app.CenterypixelEditFieldLabel.Text = 'Center y (pixel)';
            app.CenterypixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.CenterypixelEditField.ValueChangedFcn = createCallbackFcn(app, @CenterypixelEditFieldValueChanged, true);
            app.MinradiuspixelEditFieldLabel = uilabel(app.UIFigure);
            app.MinradiuspixelEditFieldLabel.Text = 'Min radius (pixel)';
            app.MinradiuspixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinradiuspixelEditField.ValueChangedFcn = createCallbackFcn(app, @MinradiuspixelEditFieldValueChanged, true);
            app.CirclefittingLabel = uilabel(app.UIFigure);
            app.CirclefittingLabel.Text = 'Circle fitting';
            app.CameraDropDownLabel = uilabel(app.UIFigure);
            app.CameraDropDownLabel.Text = 'Camera';
            app.CameraDropDown = uidropdown(app.UIFigure);
            app.CameraDropDown.Items = {'Choose 1', 'Sona', 'Retiga', 'Coolsnap'};
            app.CameraDropDown.ValueChangedFcn = createCallbackFcn(app, @CameraDropDownValueChanged, true);
            app.CameraDropDown.Value = 'Choose 1';
            app.StartProcessingButton = uibutton(app.UIFigure, 'push');
            app.StartProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @StartProcessingButtonPushed, true);
            app.StartProcessingButton.Text = 'Start Processing';
            app.MaxradiuspixelEditFieldLabel = uilabel(app.UIFigure);
            app.MaxradiuspixelEditFieldLabel.Text = 'Max radius (pixel)';
            app.MaxradiuspixelEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxradiuspixelEditField.ValueChangedFcn = createCallbackFcn(app, @MaxradiuspixelEditFieldValueChanged, true);
            app.PlotRtButton = uibutton(app.UIFigure, 'push');
            app.PlotRtButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRtButtonPushed, true);
            app.PlotRtButton.Text = 'Plot R(t)';
            app.SaveDataButton = uibutton(app.UIFigure, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CircleFittingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end